classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        addition_2                 matlab.ui.control.Button
        DegreeCheckBox             matlab.ui.control.CheckBox
        Lamp                       matlab.ui.control.Lamp
        Switch                     matlab.ui.control.Switch
        label                      matlab.ui.control.Label
        atanLabel                  matlab.ui.control.Label
        asinLabel                  matlab.ui.control.Label
        acosLabel                  matlab.ui.control.Label
        substration                matlab.ui.control.Button
        clear                      matlab.ui.control.Button
        point                      matlab.ui.control.Button
        addition                   matlab.ui.control.Button
        digit3                     matlab.ui.control.Button
        digit2                     matlab.ui.control.Button
        multiplication             matlab.ui.control.Button
        digit6                     matlab.ui.control.Button
        digit5                     matlab.ui.control.Button
        division                   matlab.ui.control.Button
        digit9                     matlab.ui.control.Button
        digit8                     matlab.ui.control.Button
        modulo                     matlab.ui.control.Button
        tan_atan                   matlab.ui.control.Button
        sin_asin                   matlab.ui.control.Button
        digit0                     matlab.ui.control.Button
        digit1                     matlab.ui.control.Button
        digit4                     matlab.ui.control.Button
        digit7                     matlab.ui.control.Button
        cos_acos                   matlab.ui.control.Button
        equal                      matlab.ui.control.Button
        exponentiel                matlab.ui.control.Button
        ln                         matlab.ui.control.Button
        log                        matlab.ui.control.Button
        factoriel                  matlab.ui.control.Button
        pow                        matlab.ui.control.Button
        shift                      matlab.ui.control.Button
        root                       matlab.ui.control.Button
        ScientificCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        dotNumber;
        enableDot = 0;
        integerNumber = 0;
        num1;
        num2;
        sign; % + - * / %
        shift_on = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: digit1
        function digit1ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 %we add float number here
                app.dotNumber = [app.dotNumber '1'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else %normal process
                result = [app.label.Text '1']; 
            end 
            new_result = str2num(result); %covert to number
            app.label.Text =num2str(new_result); %convert to string
        end

        % Button pushed function: digit2
        function digit2ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '2'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '2']; 
            end  
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit3
        function digit3ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '3'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '3']; 
            end  
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit0
        function digit0ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '0'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '0']; 
            end 
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit4
        function digit4ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '4'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '4']; 
            end 
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit5
        function digit5ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '5'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '5']; 
            end  
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit6
        function digit6ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '6'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '6']; 
            end 
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit7
        function digit7ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '7'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '7']; 
            end 
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit8
        function digit8ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '8'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '8']; 
            end  
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: digit9
        function digit9ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 
                app.dotNumber = [app.dotNumber '9'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else
                result = [app.label.Text '9']; 
            end 
            new_result = str2num(result);
            app.label.Text =num2str(new_result);
        end

        % Button pushed function: point
        function pointButtonPushed(app, event)
            app.enableDot = 1;
            app.integerNumber = str2num(app.label.Text);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; %disable dot number 
            app.cos_acos.Text = 'cos';
            app.sin_asin.Text = 'sin';
            app.tan_atan.Text = 'tan';
            app.DegreeCheckBox.Value = false;
        end

        % Button pushed function: addition
        function additionButtonPushed(app, event)
            app.sign = '+';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Button pushed function: equal
        function equalButtonPushed(app, event)
            app.num2 = str2num(app.label.Text);
            if app.sign == '+'
                app.label.Text = num2str(app.num1+app.num2);
            elseif app.sign == '-'
                app.label.Text = num2str(app.num1-app.num2);
            elseif app.sign == '*'
                app.label.Text = num2str(app.num1*app.num2);
            elseif app.sign == '/'
                app.label.Text = num2str(app.num1/app.num2);
            elseif app.sign == '%'
                app.label.Text = num2str(mod(app.num1,app.num2));
            elseif app.sign == 'pow'
                app.label.Text = num2str(power(app.num1,app.num2));
            end
        end

        % Button pushed function: substration
        function substrationButtonPushed(app, event)
            app.sign = '-';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Button pushed function: multiplication
        function multiplicationButtonPushed(app, event)
            app.sign = '*';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Button pushed function: modulo
        function moduloButtonPushed(app, event)
            app.sign = '%';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Button pushed function: division
        function divisionButtonPushed(app, event)
            app.sign = '/';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value 
                case 'On' 
                app.Lamp.Enable = true;
                app.label.Visible="on";
                app.label.Text = '0';
                app.shift.Enable = true;
                app.clear.Enable = true;
                app.DegreeCheckBox.Enable = true;
                case 'Off'
                app.Lamp.Enable = false;
                app.label.Visible="off";
                app.label.Text = '';
                app.cos_acos.Text = 'cos';
                app.sin_asin.Text = 'sin';
                app.tan_atan.Text = 'tan';
                app.DegreeCheckBox.Value = false;
                app.shift.Enable = false;
                app.clear.Enable = false;
                app.DegreeCheckBox.Enable = false;
            end 
        end

        % Button pushed function: shift
        function shiftButtonPushed(app, event)
            app.cos_acos.Text = 'acos';
            app.sin_asin.Text = 'asin';
            app.tan_atan.Text = 'atan';
        end

        % Button pushed function: cos_acos
        function cos_acosButtonPushed(app, event)
            switch app.cos_acos.Text 
                case 'cos'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(cos(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(cosd(str2num(app.label.Text)));
                end
                case 'acos'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(acos(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(acosd(str2num(app.label.Text)));
                end
            end
        end

        % Button pushed function: sin_asin
        function sin_asinButtonPushed(app, event)
            switch app.sin_asin.Text 
                case 'sin'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(sin(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(sind(str2num(app.label.Text)));
                end
                case 'asin'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(asin(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(asind(str2num(app.label.Text)));
                end
            end
        end

        % Button pushed function: tan_atan
        function tan_atanButtonPushed(app, event)
            switch app.tan_atan.Text 
                case 'tan'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(tan(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(tand(str2num(app.label.Text)));
                end
                case 'atan'
                if app.DegreeCheckBox.Value == false
                    app.label.Text = num2str(atan(str2num(app.label.Text)));
                else
                    app.label.Text = num2str(atand(str2num(app.label.Text)));
                end
            end
        end

        % Button pushed function: pow
        function powButtonPushed(app, event)
            app.sign = 'pow';
            app.num1 = str2num(app.label.Text);
            app.label.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = ' ';
            app.enableDot = 0;
        end

        % Button pushed function: root
        function rootButtonPushed(app, event)
            app.label.Text = num2str(sqrt(str2num(app.label.Text)));
        end

        % Button pushed function: factoriel
        function factorielButtonPushed(app, event)
            app.label.Text = num2str(factorial(str2num(app.label.Text)));
        end

        % Button pushed function: exponentiel
        function exponentielButtonPushed(app, event)
            app.label.Text = num2str(exp(str2num(app.label.Text)));
        end

        % Button pushed function: log
        function logButtonPushed(app, event)
            app.label.Text = num2str(log10(str2num(app.label.Text)));
        end

        % Button pushed function: ln
        function lnButtonPushed(app, event)
            app.label.Text = num2str(log(str2num(app.label.Text)));
        end

        % Button pushed function: addition_2
        function addition_2ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScientificCalculatorLabel = uilabel(app.UIFigure);
            app.ScientificCalculatorLabel.Text = 'Scientific Calculator';
            app.root = uibutton(app.UIFigure, 'push');
            app.root.ButtonPushedFcn = createCallbackFcn(app, @rootButtonPushed, true);
            app.root.Text = 'square root ';
            app.shift = uibutton(app.UIFigure, 'push');
            app.shift.ButtonPushedFcn = createCallbackFcn(app, @shiftButtonPushed, true);
            app.shift.Enable = 'off';
            app.shift.Text = 'shift';
            app.pow = uibutton(app.UIFigure, 'push');
            app.pow.ButtonPushedFcn = createCallbackFcn(app, @powButtonPushed, true);
            app.pow.Text = 'pow';
            app.factoriel = uibutton(app.UIFigure, 'push');
            app.factoriel.ButtonPushedFcn = createCallbackFcn(app, @factorielButtonPushed, true);
            app.factoriel.Text = 'factorial';
            app.log = uibutton(app.UIFigure, 'push');
            app.log.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.log.Text = 'log';
            app.ln = uibutton(app.UIFigure, 'push');
            app.ln.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.ln.Text = 'ln';
            app.exponentiel = uibutton(app.UIFigure, 'push');
            app.exponentiel.ButtonPushedFcn = createCallbackFcn(app, @exponentielButtonPushed, true);
            app.exponentiel.Text = 'e';
            app.equal = uibutton(app.UIFigure, 'push');
            app.equal.ButtonPushedFcn = createCallbackFcn(app, @equalButtonPushed, true);
            app.equal.Text = '=';
            app.cos_acos = uibutton(app.UIFigure, 'push');
            app.cos_acos.ButtonPushedFcn = createCallbackFcn(app, @cos_acosButtonPushed, true);
            app.cos_acos.Text = 'cos';
            app.digit7 = uibutton(app.UIFigure, 'push');
            app.digit7.ButtonPushedFcn = createCallbackFcn(app, @digit7ButtonPushed, true);
            app.digit7.Text = '7';
            app.digit4 = uibutton(app.UIFigure, 'push');
            app.digit4.ButtonPushedFcn = createCallbackFcn(app, @digit4ButtonPushed, true);
            app.digit4.Text = '4';
            app.digit1 = uibutton(app.UIFigure, 'push');
            app.digit1.ButtonPushedFcn = createCallbackFcn(app, @digit1ButtonPushed, true);
            app.digit1.Text = '1';
            app.digit0 = uibutton(app.UIFigure, 'push');
            app.digit0.ButtonPushedFcn = createCallbackFcn(app, @digit0ButtonPushed, true);
            app.digit0.Text = '0';
            app.sin_asin = uibutton(app.UIFigure, 'push');
            app.sin_asin.ButtonPushedFcn = createCallbackFcn(app, @sin_asinButtonPushed, true);
            app.sin_asin.Text = 'sin';
            app.tan_atan = uibutton(app.UIFigure, 'push');
            app.tan_atan.ButtonPushedFcn = createCallbackFcn(app, @tan_atanButtonPushed, true);
            app.tan_atan.Text = 'tan';
            app.modulo = uibutton(app.UIFigure, 'push');
            app.modulo.ButtonPushedFcn = createCallbackFcn(app, @moduloButtonPushed, true);
            app.modulo.Text = '%';
            app.digit8 = uibutton(app.UIFigure, 'push');
            app.digit8.ButtonPushedFcn = createCallbackFcn(app, @digit8ButtonPushed, true);
            app.digit8.Text = '8';
            app.digit9 = uibutton(app.UIFigure, 'push');
            app.digit9.ButtonPushedFcn = createCallbackFcn(app, @digit9ButtonPushed, true);
            app.digit9.Text = '9';
            app.division = uibutton(app.UIFigure, 'push');
            app.division.ButtonPushedFcn = createCallbackFcn(app, @divisionButtonPushed, true);
            app.division.Text = '/';
            app.digit5 = uibutton(app.UIFigure, 'push');
            app.digit5.ButtonPushedFcn = createCallbackFcn(app, @digit5ButtonPushed, true);
            app.digit5.Text = '5';
            app.digit6 = uibutton(app.UIFigure, 'push');
            app.digit6.ButtonPushedFcn = createCallbackFcn(app, @digit6ButtonPushed, true);
            app.digit6.Text = '6';
            app.multiplication = uibutton(app.UIFigure, 'push');
            app.multiplication.ButtonPushedFcn = createCallbackFcn(app, @multiplicationButtonPushed, true);
            app.multiplication.Text = '*';
            app.digit2 = uibutton(app.UIFigure, 'push');
            app.digit2.ButtonPushedFcn = createCallbackFcn(app, @digit2ButtonPushed, true);
            app.digit2.Text = '2';
            app.digit3 = uibutton(app.UIFigure, 'push');
            app.digit3.ButtonPushedFcn = createCallbackFcn(app, @digit3ButtonPushed, true);
            app.digit3.Text = '3';
            app.addition = uibutton(app.UIFigure, 'push');
            app.addition.ButtonPushedFcn = createCallbackFcn(app, @additionButtonPushed, true);
            app.addition.Text = '+';
            app.point = uibutton(app.UIFigure, 'push');
            app.point.ButtonPushedFcn = createCallbackFcn(app, @pointButtonPushed, true);
            app.point.Text = '.';
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Enable = 'off';
            app.clear.Text = 'C';
            app.substration = uibutton(app.UIFigure, 'push');
            app.substration.ButtonPushedFcn = createCallbackFcn(app, @substrationButtonPushed, true);
            app.substration.Text = '-';
            app.acosLabel = uilabel(app.UIFigure);
            app.acosLabel.Text = 'acos';
            app.asinLabel = uilabel(app.UIFigure);
            app.asinLabel.Text = 'asin';
            app.atanLabel = uilabel(app.UIFigure);
            app.atanLabel.Text = 'atan';
            app.label = uilabel(app.UIFigure);
            app.label.Visible = 'off';
            app.label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.DegreeCheckBox = uicheckbox(app.UIFigure);
            app.DegreeCheckBox.Enable = 'off';
            app.DegreeCheckBox.Text = 'Degree ';
            app.addition_2 = uibutton(app.UIFigure, 'push');
            app.addition_2.ButtonPushedFcn = createCallbackFcn(app, @addition_2ButtonPushed, true);
            app.addition_2.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end